classdef A2_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        Unlock2Button              matlab.ui.control.Button
        Unlock1Button              matlab.ui.control.Button
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        EndeffectorGlobalPosLabel  matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        SampleAnimationButton      matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        EmergencyStopButton        matlab.ui.control.Button
        YButton_4                  matlab.ui.control.Button
        YButton_3                  matlab.ui.control.Button
        PButton_2                  matlab.ui.control.Button
        PButton                    matlab.ui.control.Button
        RButton_2                  matlab.ui.control.Button
        RButton                    matlab.ui.control.Button
        ZButton_2                  matlab.ui.control.Button
        ZButton                    matlab.ui.control.Button
        RotateEndEffectorLabel     matlab.ui.control.Label
        YButton_2                  matlab.ui.control.Button
        YButton                    matlab.ui.control.Button
        XButton_2                  matlab.ui.control.Button
        XButton                    matlab.ui.control.Button
        TranslateEndEffectorLabel  matlab.ui.control.Label
        ResetKuka16RobotButton     matlab.ui.control.Button
        plot                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            Translation = 0.05;
            Rotation = 5;
            CheckFlag = true; % flag check for emergency stop button
            steps = 150; %steps for quintic polynomial trajectory
        % place your class properties here
        K16 % Where I will put the loaded UR5
        qrK16 = [0	0  pi/2	0	0	0];
    end

        methods (Access = private)

        function Printfkine(app, newPose) %Fkine label print
            xPos = newPose(1, 4);
            yPos = newPose(2, 4);
            zPos = newPose(3, 4);
            app.Label.Text = num2str(xPos);
            app.Label_2.Text = num2str(yPos);
            app.Label_3.Text = num2str(zPos);
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;    

            app.K16 = Kuka16;
            app.K16.model.base = transl([-0.5,0,0]);
            app.K16.model.animate(app.qrK16);
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.ResetKuka16RobotButton.Enable = true;
            
        end

        % Button pushed function: ResetKuka16RobotButton
        function ResetKuka16RobotButtonPushed(app, event)

            q1 = app.K16.model.getpos();
            q2 = zeros(1,app.K16.model.n);
            returnMatrix = jtraj(q1,q2,app.steps);
            for i = 1:app.steps
                app.K16.model.animate(returnMatrix(i,:));
                drawnow();
%                 app.PrintFkine(app.K16.model.fkineUTS(app.K16.model.getpos));
            end
            %enables movement after homing is complete
            app.SampleAnimationButton.Enable = true;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Move end effector in pos X direction
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            translate = transl(app.Translation, 0, 0);
            newPos = currentpos * translate;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
%             app.Printfkine(newPos);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Move end effector in pos X direction
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            translate = transl(-app.Translation, 0, 0);
            newPos = currentpos * translate;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
%             app.Printfkine(newPos);            
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Move end effector in pos Y direction
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            translate = transl(0, app.Translation, 0);
            newPos = currentpos * translate;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
%             app.Printfkine(newPos);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Move end effector in pos Y direction
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            translate = transl(0, -app.Translation, 0);
            newPos = currentpos * translate;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
%             app.Printfkine(newPos);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Move end effector in pos Z direction
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            translate = transl(0, 0, app.Translation);
            newPos = currentpos * translate;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
%             app.Printfkine(newPos); 
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            % Move end effector in pos Z direction
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            translate = transl(0, 0, -app.Translation);
            newPos = currentpos * translate;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
%             app.Printfkine(newPos); 
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotx(rollAngle); % Create a roll rotation matrix
            newPos = currentpos * RollRotation;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotx(-rollAngle); % Create a roll rotation matrix
            newPos = currentpos * RollRotation;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = troty(rollAngle); % Create a roll rotation matrix
            newPos = currentpos * RollRotation;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);  
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = troty(-rollAngle); % Create a roll rotation matrix
            newPos = currentpos * RollRotation;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);              
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotz(rollAngle); % Create a roll rotation matrix
            newPos = currentpos * RollRotation;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);     
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotz(-rollAngle); % Create a roll rotation matrix
            newPos = currentpos * RollRotation;
            q = app.K16.model.ikine(newPos);
            app.K16.model.animate(q);     
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            %emergency button changes flag, no need for if statement
            %keeping just in case
            
            app.CheckFlag = ~app.CheckFlag;
            if(app.CheckFlag)
                app.Lamp.Color = 'g';
                app.SampleAnimationButton.Enable = true;
                app.HomeRobotButton.Enable = true;
            else %if pushed, change lamp colour and disable animations.
                app.Lamp.Color = 'r';
                app.SampleAnimationButton.Enable = false;
                app.ResetKuka16RobotButton.Enable = false;
%                 app.Unlock1Button.Enable = true;
%                 app.Unlock2Button.Enable = false;
            end
            app.EmergencyStopButton.Enable = false;
            % app.HomeCheck(app.KR6.model.getpos);
        end

        % Button pushed function: SampleAnimationButton
        function SampleAnimationButtonPushed(app, event)
            %Simple Demonstration movement
            while(app.CheckFlag)
                q1 = zeros(1,app.K16.model.n); %random joints to move between
                q2 = deg2rad([180, -20, 45, -30, -70, 90]);
                
                firstMove = jtraj(q1,q2,app.steps);
                for i = 1:app.steps
                    app.K16.model.animate(firstMove(i,:))
                    drawnow();
%                     app.PrintFKine(app.K16.model.fkineUTS(app.K16.model.getpos));
                    if(~app.CheckFlag)
                        pause();
                    end
                end
                %reversing the movement
                backMove = jtraj(q2,q1,app.steps);
                for i = 1:app.steps
                    app.K16.model.animate(backMove(i,:));
                    drawnow();
%                     app.PrintFKine(app.K16.model.fkineUTS(app.K16.model.getpos));
                    if(~app.CheckFlag) %emergency button switches flag to pause, still can use jogging
                        pause();
                    end
                end
                 % if(app.flag)
                %     break;
                % end
            end
        end

        % Button pushed function: Unlock1Button
        function Unlock1ButtonPushed(app, event)
            %emergency button changes flag, no need for if statement
            %keeping just in case
            
            app.CheckFlag = ~app.CheckFlag;
            if(app.CheckFlag)
                app.Lamp.Color = 'r';
                app.Unlock1Button.Enable = true;
            else %if pushed, change lamp colour and disable animations.
                app.Lamp.Color = 'r';
                app.Unlock1Button.Enable = false;
                app.Unlock2Button.Enable = true;
            end
            app.Unlock1Button.Enable = false;
            % app.HomeCheck(app.KR6.model.getpos);
        end

        % Button pushed function: Unlock2Button
        function Unlock2ButtonPushed(app, event)
            app.CheckFlag = ~app.CheckFlag;
            if(app.CheckFlag)
                app.Lamp.Color = 'r';
                app.Unlock2Button.Enable = true;
            else %if pushed, change lamp colour and disable animations.
                app.Lamp.Color = 'g';
                app.Unlock1Button.Enable = false;
                app.Unlock2Button.Enable =  false;  
                app.SampleAnimationButton.Enable = true;
                app.ResetKuka16RobotButton.Enable = true;
                app.EmergencyStopButton.Enable = true;
            end
            app.Unlock2Button.Enable = false;
            % app.HomeCheck(app.KR6.model.getpos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.ResetKuka16RobotButton = uibutton(app.figure1, 'push');
            app.ResetKuka16RobotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetKuka16RobotButtonPushed, true);
            app.ResetKuka16RobotButton.Text = 'Reset Kuka16 Robot';
            app.TranslateEndEffectorLabel = uilabel(app.figure1);
            app.TranslateEndEffectorLabel.Text = 'Translate End Effector';
            app.XButton = uibutton(app.figure1, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+ X';
            app.XButton_2 = uibutton(app.figure1, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '- X';
            app.YButton = uibutton(app.figure1, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+ Y';
            app.YButton_2 = uibutton(app.figure1, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '- Y';
            app.RotateEndEffectorLabel = uilabel(app.figure1);
            app.RotateEndEffectorLabel.Text = 'Rotate End Effector';
            app.ZButton = uibutton(app.figure1, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+ Z';
            app.ZButton_2 = uibutton(app.figure1, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '- Z';
            app.RButton = uibutton(app.figure1, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '+ R';
            app.RButton_2 = uibutton(app.figure1, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = '- R';
            app.PButton = uibutton(app.figure1, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = '+ P';
            app.PButton_2 = uibutton(app.figure1, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Text = '- P';
            app.YButton_3 = uibutton(app.figure1, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '+ Y';
            app.YButton_4 = uibutton(app.figure1, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = '- Y';
            app.EmergencyStopButton = uibutton(app.figure1, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.Lamp = uilamp(app.figure1);
            app.SampleAnimationButton = uibutton(app.figure1, 'push');
            app.SampleAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @SampleAnimationButtonPushed, true);
            app.SampleAnimationButton.Text = 'Sample Animation';
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z:';
            app.EndeffectorGlobalPosLabel = uilabel(app.figure1);
            app.EndeffectorGlobalPosLabel.Text = 'End-effector Global Pos';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.figure1);
            app.Label_3.Text = '';
            app.Unlock1Button = uibutton(app.figure1, 'push');
            app.Unlock1Button.ButtonPushedFcn = createCallbackFcn(app, @Unlock1ButtonPushed, true);
            app.Unlock1Button.Text = 'Unlock 1';
            app.Unlock2Button = uibutton(app.figure1, 'push');
            app.Unlock2Button.ButtonPushedFcn = createCallbackFcn(app, @Unlock2ButtonPushed, true);
            app.Unlock2Button.Text = 'Unlock 2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2_GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end