classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        EndEffectorGlobalPositionTextArea_2  matlab.ui.control.TextArea
        EndEffectorGlobalPositionTextArea_2Label  matlab.ui.control.Label
        EndEffectorGlobalPositionTextArea  matlab.ui.control.TextArea
        EndEffectorGlobalPositionTextAreaLabel  matlab.ui.control.Label
        EmergancyStopButton        matlab.ui.control.Button
        YButton_4                  matlab.ui.control.Button
        YButton_3                  matlab.ui.control.Button
        PButton_2                  matlab.ui.control.Button
        PButton                    matlab.ui.control.Button
        RButton_2                  matlab.ui.control.Button
        RButton                    matlab.ui.control.Button
        ZButton_2                  matlab.ui.control.Button
        ZButton                    matlab.ui.control.Button
        YButton_2                  matlab.ui.control.Button
        YButton                    matlab.ui.control.Button
        XButton_2                  matlab.ui.control.Button
        XButton                    matlab.ui.control.Button
        RotateEndEffectorLabel     matlab.ui.control.Label
        TranslateEndEffectorLabel  matlab.ui.control.Label
        LoadResetKukaButton        matlab.ui.control.Button
        plot                       matlab.ui.control.UIAxes
    end

    
    properties (Access =     private)
            K16  ;  
            Translation = 0.02;
            Rotation = 5;
    end
    
    methods (Access = private)
        function Printfkine(app,newPose)
            xPos = newPose(1,4);
            yPos = newPose(2, 4);
            yPos = newPose(3, 4);
            label.Text_1 = num2str(xPos);
            label.Text_2 = num2str(yPos);
            label.Text_3 = num2str(yPos);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
clf
            %             Code to run once GUI is loaded successfully
            ax = gca;

            robot = Kuka16;
            app.K16 = robot;
            %view(3)
            robot.model.animate(zeros(1,robot.model.n));
            drawnow();

%             Delete the green floor surface (need to count backwards
%             because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end


        end

        % Button pushed function: LoadResetKukaButton
        function LoadResetKukaButtonPushed(app, event)
            clf;
            robot = Kuka16;
            app.K16 = robot;
            %view(3)
            robot.model.animate(zeros(1,robot.model.n));
            drawnow();
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Move end effector in pos X direction
            currentpos = app.K16.model.fkine(app.K16.model.getpos()).T;
            translate = transl(app.Translation, 0, 0);
            newPos = currentpos * translate;
            q = robot.model.ikine(newpos);
            app.K16.model.animate(q);
            app.Printfkine(newPos);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Move end effector in neg X direction
            currentpos = robot.model.fkine(robot.model.KP6.getpos()).T;
            translate = transl(-app.Translation, 0, 0);
            newPos = currentpos * translate;
            q = robot.model.ikine(newpos);
            robot.model.animate(q);
            app.Printfkine(newPos);          
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Move end effector in pos Y direction
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            translate = transl(0, app.Translation, 0);
            newPos = currentpos * translate;
            q = robot.model.ikine(newpos);
            robot.model.animate(q);
            app.Printfkine(newPos);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Move end effector in neg Y direction
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            translate = transl(0, -app.Translation, 0);
            newPos = currentpos * translate;
            q = robot.model.ikine(newpos);
            robot.model.animate(q);
            app.Printfkine(newPos);            
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Move end effector in pos Z direction
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            translate = transl(0, 0, app.Translation);
            newPos = currentpos * translate;
            q = robot.model.ikine(newpos);
            robot.model.animate(q);
            app.Printfkine(newPos);            
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            % Move end effector in neg Z direction
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            translate = transl(0, 0, -app.Translation);
            newPos = currentpos * translate;
            q = robot.model.ikine(newpos);
            robot.model.animate(q);
            app.Printfkine(newPos);              
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotx(rollAngle); % Create a roll rotation matrix
            newPos = currentpos * rollRotation;
            q = robot.model.ikine(newPos);
            robot.model.animate(q);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotx(-rollAngle); % Create a roll rotation matrix
            newPos = currentpos * rollRotation;
            q = robot.model.ikine(newPos);
            robot.model.animate(q);  
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = troty(rollAngle); % Create a roll rotation matrix
            newPos = currentpos * rollRotation;
            q = robot.model.ikine(newPos);
            robot.model.animate(q);  
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = troty(-rollAngle); % Create a roll rotation matrix
            newPos = currentpos * rollRotation;
            q = robot.model.ikine(newPos);
            robot.model.animate(q);      
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotz(rollAngle); % Create a roll rotation matrix
            newPos = currentpos * rollRotation;
            q = robot.model.ikine(newPos);
            robot.model.animate(q);  
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            currentpos = robot.model.fkine(robot.model.getpos()).T;
            rollAngle = deg2rad(app.Rotation);
            RollRotation = trotz(-rollAngle); % Create a roll rotation matrix
            newPos = currentpos * rollRotation;
            q = robot.model.ikine(newPos);
            robot.model.animate(q);              
        end

        % Button pushed function: EmergancyStopButton
        function EmergancyStopButtonPushed(app, event)
            
        end

        % Value changed function: EndEffectorGlobalPositionTextArea
        function EndEffectorGlobalPositionTextAreaValueChanged(app, event)
            value = app.EndEffectorGlobalPositionTextArea.Value;
            
        end

        % Value changed function: EndEffectorGlobalPositionTextArea_2
        function EndEffectorGlobalPositionTextArea_2ValueChanged(app, event)
            value = app.EndEffectorGlobalPositionTextArea_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.LoadResetKukaButton = uibutton(app.figure1, 'push');
            app.LoadResetKukaButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResetKukaButtonPushed, true);
            app.LoadResetKukaButton.Text = 'Load/Reset Kuka';
            app.TranslateEndEffectorLabel = uilabel(app.figure1);
            app.TranslateEndEffectorLabel.Text = 'Translate End Effector';
            app.RotateEndEffectorLabel = uilabel(app.figure1);
            app.RotateEndEffectorLabel.Text = 'Rotate End Effector';
            app.XButton = uibutton(app.figure1, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+ X';
            app.XButton_2 = uibutton(app.figure1, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '- X';
            app.YButton = uibutton(app.figure1, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+ Y';
            app.YButton_2 = uibutton(app.figure1, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '- Y';
            app.ZButton = uibutton(app.figure1, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+ Z';
            app.ZButton_2 = uibutton(app.figure1, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '- Z';
            app.RButton = uibutton(app.figure1, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '+ R';
            app.RButton_2 = uibutton(app.figure1, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = '- R';
            app.PButton = uibutton(app.figure1, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = '+ P';
            app.PButton_2 = uibutton(app.figure1, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Text = '- P';
            app.YButton_3 = uibutton(app.figure1, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '+ Y';
            app.YButton_4 = uibutton(app.figure1, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = '- Y';
            app.EmergancyStopButton = uibutton(app.figure1, 'push');
            app.EmergancyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergancyStopButtonPushed, true);
            app.EmergancyStopButton.Text = 'Emergancy Stop ';
            app.EndEffectorGlobalPositionTextAreaLabel = uilabel(app.figure1);
            app.EndEffectorGlobalPositionTextAreaLabel.Text = 'End Effector Global Position';
            app.EndEffectorGlobalPositionTextArea = uitextarea(app.figure1);
            app.EndEffectorGlobalPositionTextArea.ValueChangedFcn = createCallbackFcn(app, @EndEffectorGlobalPositionTextAreaValueChanged, true);
            app.EndEffectorGlobalPositionTextArea.Value = {'X - '; 'Y - '; 'Z - '};
            app.EndEffectorGlobalPositionTextArea_2Label = uilabel(app.figure1);
            app.EndEffectorGlobalPositionTextArea_2Label.Text = 'End Effector Global Position';
            app.EndEffectorGlobalPositionTextArea_2 = uitextarea(app.figure1);
            app.EndEffectorGlobalPositionTextArea_2.ValueChangedFcn = createCallbackFcn(app, @EndEffectorGlobalPositionTextArea_2ValueChanged, true);
            app.EndEffectorGlobalPositionTextArea_2.Value = {'R - '; 'P - '; 'Y - '};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end